classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image3    matlab.ui.control.Image
        Image4    matlab.ui.control.Image
        Image5    matlab.ui.control.Image
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
    end

    
    properties (Access = private)
        filename % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [imgfilename, imgpathname]=uigetfile({'*.jpg;*.png'},'Select a RGB image');
            app.filename = imgpathname + "\" + imgfilename;
            app.Image4.ImageSource = app.filename;   
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            successful = sudorun(app.filename);
            if successful
                app.Image5.ImageSource = "output.jpg"; 
            else
                app.Image5.ImageSource = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'background1.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image5 = uiimage(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '输入数独图片';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '计算';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end